classdef DeepFryer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DeepfryerUIFigure       matlab.ui.Figure
        ResetimageButton        matlab.ui.control.Button
        SharpnessSlider         matlab.ui.control.Slider
        SharpnessSliderLabel    matlab.ui.control.Label
        ContrastSlider          matlab.ui.control.Slider
        ContrastSliderLabel     matlab.ui.control.Label
        ReturntomainmenuButton  matlab.ui.control.Button
        SaveImageButton         matlab.ui.control.Button
        UploadImageButton       matlab.ui.control.Button
        SaturationSlider        matlab.ui.control.Slider
        SaturationSliderLabel   matlab.ui.control.Label
        BrightnessSlider        matlab.ui.control.Slider
        BrightnessSliderLabel   matlab.ui.control.Label
        DeepFryerLabel          matlab.ui.control.Label
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Image %original image
        ImgTemp %temporary image property that is edited by the sliders
        ImgSat %property for image saturation testing
        save %property for saving images
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadImageButton
        function UploadImageButtonPushed(app, event)
            %uploading image
            try
                [filename,filepath] = uigetfile({'*.*;*.jpg;*.png;*.bmp;*.oct'}, 'Select File to Open');
                fullname = [filepath, filename];
                app.Image = imread(fullname);
                app.ImgTemp = app.Image;
                imshow(app.Image,'parent',app.UIAxes);
                app.BrightnessSlider.Value = 0;
                app.ContrastSlider.Value = 0;
                app.SharpnessSlider.Value = 0;
                app.SaturationSlider.Value = 0; 
            catch 
                noup = "No image uploaded.";
                warndlg(noup)
            end
        end

        % Button pushed function: ReturntomainmenuButton
        function ReturntomainmenuButtonPushed(app, event)
            question = "Are you sure you want to return to the main menu?";
            answer = questdlg(question,"Return to main menu","yes","no","yes"); 
            switch answer
                case "yes"
                    MainMenu;
                    app.DeepfryerUIFigure.Visible = 'off';
                case "no"
                    delete(answer);
            end
        end

        % Value changed function: BrightnessSlider
        function BrightnessSliderValueChanging(app, event)
            changingValue = event.Value;
            previous = event.PreviousValue;
            if previous > changingValue
                if previous >= 0
                    if changingValue >= 0
                        app.ImgTemp = app.ImgTemp - changingValue;
                    elseif changingValue < 0
                        app.ImgTemp = app.ImgTemp - abs(changingValue);
                    end
                elseif previous < 0
                    if changingValue < 0    
                        app.ImgTemp = app.ImgTemp - abs(changingValue);
                    end
                end
            elseif previous < changingValue
                if previous >= 0
                    if changingValue >= 0
                        app.ImgTemp = app.ImgTemp + changingValue;
                    end
                elseif previous < 0
                    if changingValue < 0
                        app.ImgTemp = app.ImgTemp + abs(changingValue);
                    elseif changingValue >= 0
                        app.ImgTemp = app.ImgTemp + abs(previous) + changingValue;
                    end
                end
            end
            imshow(app.ImgTemp,'parent',app.UIAxes);
        end

        % Value changed function: SharpnessSlider
        function SharpnessSliderValueChanging(app, event)
            changingValue = event.Value;
            app.ImgTemp = imsharpen(app.ImgTemp,'Amount',changingValue/10);
            imshow(app.ImgTemp,'parent',app.UIAxes);
        end

        % Value changed function: ContrastSlider
        function ContrastSliderValueChanging(app, event)
            changingValue = event.Value;
            app.ImgTemp = imadjust(app.ImgTemp,[],[],changingValue);
            % testing values [0 .2 0; .1 .5 .1]
            imshow(app.ImgTemp,'parent',app.UIAxes);
        end

        % Value changed function: SaturationSlider
        function SaturationSliderValueChanging(app, event)
            changingValue = event.Value;
            app.ImgSat = rgb2hsv(app.ImgTemp);
%             [h,s,v] = imsplit(app.ImgSat);
%             saturationFactor = changingValue;
%             s_sat = s*saturationFactor;
%             HSV_sat = cat(3,h,s_sat,v);
            app.ImgSat(:,:,2) = changingValue;
            app.ImgTemp = hsv2rgb(app.ImgSat);
            imshow(app.ImgTemp,'parent',app.UIAxes);
        end

        % Button pushed function: ResetimageButton
        function ResetimageButtonPushed(app, event)
            try
                app.ImgTemp = app.Image;
                imshow(app.Image,'parent',app.UIAxes);
                app.BrightnessSlider.Value = 0;
                app.ContrastSlider.Value = 0;
                app.SharpnessSlider.Value = 0;
                app.SaturationSlider.Value = 0;
            catch 
                noup = "No image uploaded";
                warndlg(noup)
            end
            
        end

        % Button pushed function: SaveImageButton
        function SaveImageButtonPushed(app, event)
            if(isempty(app.Image))
                noup = "No image to be saved.";
                warndlg(noup)
            else
                filter = {'*.jpg';'*.jpeg';'*.png';'*.*'};
                [file,path] = uiputfile(filter,'save image','SavedImage');
                app.save = [path,file];
                imwrite(app.ImgTemp,app.save);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DeepfryerUIFigure = uifigure('Visible', 'off');
            app.DeepfryerUIFigure.Name = 'Deep fryer';
            app.UIAxes = uiaxes(app.DeepfryerUIFigure);
            app.UIAxes.Visible = 'off';
            app.DeepFryerLabel = uilabel(app.DeepfryerUIFigure);
            app.DeepFryerLabel.Text = {'Deep'; 'Fryer'};
            app.BrightnessSliderLabel = uilabel(app.DeepfryerUIFigure);
            app.BrightnessSliderLabel.Text = 'Brightness';
            app.BrightnessSlider = uislider(app.DeepfryerUIFigure);
            app.BrightnessSlider.Limits = [-50 50];
            app.BrightnessSlider.MajorTicks = [-50 0 50];
            app.BrightnessSlider.ValueChangedFcn = createCallbackFcn(app, @BrightnessSliderValueChanging, true);
            app.SaturationSliderLabel = uilabel(app.DeepfryerUIFigure);
            app.SaturationSliderLabel.Text = 'Saturation';
            app.SaturationSlider = uislider(app.DeepfryerUIFigure);
            app.SaturationSlider.Limits = [0 1];
            app.SaturationSlider.MajorTicks = [0 1];
            app.SaturationSlider.MajorTickLabels = {'0', '1', ''};
            app.SaturationSlider.ValueChangedFcn = createCallbackFcn(app, @SaturationSliderValueChanging, true);
            app.SaturationSlider.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UploadImageButton = uibutton(app.DeepfryerUIFigure, 'push');
            app.UploadImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.UploadImageButton.Text = 'Upload Image';
            app.SaveImageButton = uibutton(app.DeepfryerUIFigure, 'push');
            app.SaveImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButtonPushed, true);
            app.SaveImageButton.Text = 'Save Image';
            app.ReturntomainmenuButton = uibutton(app.DeepfryerUIFigure, 'push');
            app.ReturntomainmenuButton.ButtonPushedFcn = createCallbackFcn(app, @ReturntomainmenuButtonPushed, true);
            app.ReturntomainmenuButton.Text = 'Return to main menu';
            app.ContrastSliderLabel = uilabel(app.DeepfryerUIFigure);
            app.ContrastSliderLabel.Text = 'Contrast';
            app.ContrastSlider = uislider(app.DeepfryerUIFigure);
            app.ContrastSlider.Limits = [0 2];
            app.ContrastSlider.MajorTicks = [0 2];
            app.ContrastSlider.MajorTickLabels = {'0', '2', ''};
            app.ContrastSlider.ValueChangedFcn = createCallbackFcn(app, @ContrastSliderValueChanging, true);
            app.ContrastSlider.MinorTicks = [0 0.5 1 1.5 2];
            app.SharpnessSliderLabel = uilabel(app.DeepfryerUIFigure);
            app.SharpnessSliderLabel.Text = 'Sharpness';
            app.SharpnessSlider = uislider(app.DeepfryerUIFigure);
            app.SharpnessSlider.Limits = [0 200];
            app.SharpnessSlider.MajorTicks = [0 200];
            app.SharpnessSlider.ValueChangedFcn = createCallbackFcn(app, @SharpnessSliderValueChanging, true);
            app.SharpnessSlider.MinorTicks = [0 10 20 30 40 50 60 70 80 90 100 110 120 130 140 150 160 170 180 190 200];
            app.ResetimageButton = uibutton(app.DeepfryerUIFigure, 'push');
            app.ResetimageButton.ButtonPushedFcn = createCallbackFcn(app, @ResetimageButtonPushed, true);
            app.ResetimageButton.Text = 'Reset image';
            % Show the figure after all components are created
            app.DeepfryerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DeepFryer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DeepfryerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DeepfryerUIFigure)
        end
    end
end