classdef NoiseRemoverMainv3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        MainMenuButton        matlab.ui.control.Button
        ClearFilterButton     matlab.ui.control.Button
        ApplyFilterButton     matlab.ui.control.Button
        SharpnessSlider       matlab.ui.control.Slider
        SharpnessSliderLabel  matlab.ui.control.Label
        FilterSlider          matlab.ui.control.Slider
        FilterSliderLabel     matlab.ui.control.Label
        SaveImageButton       matlab.ui.control.Button
        FilterDropDown        matlab.ui.control.DropDown
        FilterDropDownLabel   matlab.ui.control.Label
        UploadImageButton     matlab.ui.control.Button
        NoiseRemoverLabel     matlab.ui.control.Label
        UIAxes                matlab.ui.control.UIAxes
    end

    properties (Access = public)
        Img
        ImgTemp
        fSlide
        sSlide
        dOpt
        ImageFile
        filtdImage
        sharpimg
        filtloop
        save
    end
        methods (Access = public)
        
         %For RGB
        function aFilter(app) %Applying filters
            fSlidee = round(app.fSlide);
            %image = app.ImageFile;
%             if image == empty
%                 image = app.ImageFile;
%             else
%                 image = app.filtdImage
%             end
            switch app.dOpt
                case "Averaging" %Averaging
                    for a = 1:fSlidee
                        if a == 1
                         image = app.ImageFile;
                        else
                          image = app.filtdImage*255;
                        end
                        
                        imeg = fspecial('average',3);
                        RR = image(:,:,1);
                        GG = image(:,:,2);
                        BB = image(:,:,3);
                        img(:,:,1) = filter2(imeg, RR);
                        img(:,:,2) = filter2(imeg, GG);
                        img(:,:,3) = filter2(imeg, BB);
                        imshow(img/255, 'parent', app.UIAxes);
                        app.filtdImage = img/255;
                    end
                case "Median" %Median
                     for a = 1:fSlidee
                         if a == 1
                         image = app.ImageFile;
                        else
                          image = app.filtdImage;
                        end
                        
                         RR = image(:,:,1);
                         GG = image(:,:,2);
                         BB = image(:,:,3);
                         img(:,:,1) = medfilt2(RR);
                         img(:,:,2) = medfilt2(GG);
                         img(:,:,3) = medfilt2(BB);
                         imshow(img, 'parent', app.UIAxes);
                         app.filtdImage = img;
                     end
                case "Outlier" %Outlier
                    for a = 1:fSlidee
                        if a == 1
                         image = app.ImageFile;
                        else
                          image = app.filtdImage;
                        end
                        temp_f = [0.125 0.125 0.125; 0.125 0 0.125; 0.125 0.125 0.125];
                        temp_imgd = im2double(image);
                        RR = temp_imgd(:,:,1);
                        GG = temp_imgd(:,:,2);
                        BB = temp_imgd(:,:,3);
                        temp_imgf(:,:,1) = filter2(temp_f, RR);
                        temp_imgf(:,:,2) = filter2(temp_f, GG);
                        temp_imgf(:,:,3) = filter2(temp_f, BB);
                        temp_r = abs(temp_imgd-temp_imgf)- 0.1 > 0;
                        img = im2uint8(temp_r.*temp_imgf+(1-temp_r).*temp_imgd);
                        imshow(img, 'parent', app.UIAxes);
                        app.filtdImage = img;
                    end
                otherwise
                   app.ImgTemp = rgb2gray(app.ImageFile);
                   app.updateImage();
            end
        end
        
        %For Black&White
        function aFilter2(app) %Applying filters
%             app.sSlide = 1
%             app.fSlide = 1
            fSlidee = round(app.fSlide);
            %image = app.ImageFile;
            switch app.dOpt
                case "Averaging" %Averaging
                    for a = 1:fSlidee
                        if a == 1
                         image = app.ImageFile;
                        else
                          image = app.filtdImage*255;
                        end
                        imeg = fspecial('average',3);
                        img = filter2(imeg, image);
                        imshow(img/255, 'parent', app.UIAxes);
                        app.filtdImage = img/255;
                    end
                case "Median" %Median
                     for a = 1:fSlidee
                         if a == 1
                         image = app.ImageFile;
                        else
                          image = app.filtdImage;
                        end
                         img = medfilt2(image);
                         imshow(img, 'parent', app.UIAxes);
                         app.filtdImage = img;
                     end
                case "Outlier" %Outlier
                    for a = 1:fSlidee
                        if a == 1
                         image = app.ImageFile;
                        else
                          image = app.filtdImage;
                        end
                        temp_f = [0.125 0.125 0.125; 0.125 0 0.125; 0.125 0.125 0.125];
                        temp_imgd = im2double(image);
                        temp_imgf = filter2(temp_f, temp_imgd);
                        temp_r = abs(temp_imgd-temp_imgf)- 0.1 > 0;
                        img = im2uint8(temp_r.*temp_imgf+(1-temp_r).*temp_imgd);
                        imshow(img, 'parent', app.UIAxes);
                        app.filtdImage = img;
                    end
                otherwise
                   app.ImgTemp = rgb2gray(app.ImageFile);
                   app.updateImage();
            end
        end
        
        function aSharp(app) %Applying sharpness
            sSlidee = round(app.sSlide);
            app.sharpimg = imsharpen(app.filtdImage,'Amount',sSlidee/10);
            imshow(app.sharpimg, 'parent', app.UIAxes);  
        end
        
        function uploadImg(app) %For Upload Image Button
           try
               [filename,filepath] = uigetfile({'*.*;*.jpg;*.png;*.bmp;*.oct'}, 'Select File to Open');
               fullname = string([filepath, filename]);
               Image = imread(fullname);
               app.ImageFile = Image;
               imshow(app.ImageFile, 'parent', app.UIAxes)
               app.SharpnessSlider.Value = 1;
           app.FilterSlider.Value = 1;
           catch
               noup = "No image is uploaded.";
               warndlg(noup)
           end
        end
        
        function cFilter(app) %For Clear Filter Button
           app.SharpnessSlider.Value = 1;
           app.FilterSlider.Value = 1;
           imshow(app.ImageFile, 'parent', app.UIAxes)
        end
        
        function saveas(app) %For Save Button
           %SAVE IMAGE 
           if(isempty(app.ImageFile))
               noup = "No image to be saved.";
               warndlg(noup);
           else
               filter = {'*.jpg';'*.jpeg';'*.png';'*.*'};
               [file,path] = uiputfile(filter,'save image','SavedImage');
               app.save = [path,file];
               imwrite(app.sharpimg,app.save);
           end
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadImageButton
        function UploadImageButtonPushed(app, event)
           try
              app.uploadImg();
              delete(app.filtdImage);
           catch
               
           end
        end

        % Button pushed function: ApplyFilterButton
        function ApplyFilterButtonPushed(app, event)
            app.fSlide = get(app.FilterSlider, 'Value');
            app.sSlide = get(app.SharpnessSlider, 'Value');
            %SsliderValue = get(app.SharpnessSlider, 1);
            if size(app.ImageFile,3) == 3
            app.aFilter();
            app.aSharp();
           else   
            app.aFilter2();
            app.aSharp();
            
           end
%            app.updateImage();
        end

        % Button pushed function: ClearFilterButton
        function ClearFilterButtonPushed(app, event)
           app.cFilter(); 
        end

        % Button pushed function: SaveImageButton
        function SaveImageButtonPushed(app, event)
            app.saveas()
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)

        end

        % Value changing function: SharpnessSlider
        function SharpnessSliderValueChanging(app, event)
            app.sSlide = event.Value;
            ssSlide = round(app.sSlide);
        end

        % Value changing function: FilterSlider
        function FilterSliderValueChanging(app, event)
            app.fSlide = event.Value;
            ffSlide = round(app.fSlide);
        end

        % Value changed function: FilterDropDown
        function FilterDropDownValueChanged(app, event)
            app.dOpt = app.FilterDropDown.Value;
        end

        % Button pushed function: MainMenuButton
        function MainMenuButtonPushed(app, event)
            question = "Are you sure you want to return to the main menu?";
            answer = questdlg(question,"Return to main menu","yes","no","yes"); 
            switch answer
                case "yes"
                    MainMenu;
                    app.UIFigure.Visible = 'off';
                case "no"
                    delete(answer);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.Visible = 'off';
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.NoiseRemoverLabel = uilabel(app.UIFigure);
            app.NoiseRemoverLabel.Text = 'Noise Remover';
            app.UploadImageButton = uibutton(app.UIFigure, 'push');
            app.UploadImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.UploadImageButton.Text = 'Upload Image';
            app.FilterDropDownLabel = uilabel(app.UIFigure);
            app.FilterDropDownLabel.Text = 'Filter';
            app.FilterDropDown = uidropdown(app.UIFigure);
            app.FilterDropDown.Items = {'-- Select Item --', 'Averaging', 'Median', 'Outlier'};
            app.FilterDropDown.ValueChangedFcn = createCallbackFcn(app, @FilterDropDownValueChanged, true);
            app.FilterDropDown.Placeholder = '-- Select Item --';
            app.FilterDropDown.Value = '-- Select Item --';
            app.SaveImageButton = uibutton(app.UIFigure, 'push');
            app.SaveImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButtonPushed, true);
            app.SaveImageButton.Text = 'Save Image';
            app.FilterSliderLabel = uilabel(app.UIFigure);
            app.FilterSliderLabel.Text = 'Filter';
            app.FilterSlider = uislider(app.UIFigure);
            app.FilterSlider.Limits = [1 10];
            app.FilterSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.FilterSlider.MajorTickLabels = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.FilterSlider.ValueChangingFcn = createCallbackFcn(app, @FilterSliderValueChanging, true);
            app.FilterSlider.Value = 1;
            app.SharpnessSliderLabel = uilabel(app.UIFigure);
            app.SharpnessSliderLabel.Text = 'Sharpness';
            app.SharpnessSlider = uislider(app.UIFigure);
            app.SharpnessSlider.Limits = [1 200];
            app.SharpnessSlider.MajorTicks = [1 50 100 150 200];
            app.SharpnessSlider.ValueChangingFcn = createCallbackFcn(app, @SharpnessSliderValueChanging, true);
            app.SharpnessSlider.Value = 1;
            app.ApplyFilterButton = uibutton(app.UIFigure, 'push');
            app.ApplyFilterButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyFilterButtonPushed, true);
            app.ApplyFilterButton.Text = 'Apply Filter';
            app.ClearFilterButton = uibutton(app.UIFigure, 'push');
            app.ClearFilterButton.ButtonPushedFcn = createCallbackFcn(app, @ClearFilterButtonPushed, true);
            app.ClearFilterButton.Text = 'Clear Filter';
            app.MainMenuButton = uibutton(app.UIFigure, 'push');
            app.MainMenuButton.ButtonPushedFcn = createCallbackFcn(app, @MainMenuButtonPushed, true);
            app.MainMenuButton.Text = 'Main Menu';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NoiseRemoverMainv3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end