classdef Upscaling < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UpscaleUIFigure         matlab.ui.Figure
        Scale1Label             matlab.ui.control.Label
        ScaleSlider             matlab.ui.control.Slider
        ReturntomainmenuButton  matlab.ui.control.Button
        SaveImageButton         matlab.ui.control.Button
        UploadImageButton       matlab.ui.control.Button
        UpscaleButton           matlab.ui.control.Button
        UpscalingLabel          matlab.ui.control.Label
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Image % image when uploaded
        ImgTmp %temporary variable to be image edited
        sloider %slider value to be passed to upscale button
        save %for saving image
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadImageButton
        function UploadImageButtonPushed(app, event)
           %uploading image
           try
               [filename,filepath] = uigetfile({'*.*;*.jpg;*.png;*.bmp;*.oct'}, 'Select File to Open');
               fullname = [filepath, filename];
               app.Image = imread(fullname);
               app.ImgTmp = app.Image;
               imshow(app.Image,'parent',app.UIAxes);
               app.ScaleSlider.Value = 1;
               app.Scale1Label.Text = "Scale : 1";
           catch 
               noup = "No image uploaded.";
               warndlg(noup);
           end
        end

        % Button pushed function: ReturntomainmenuButton
        function ReturntomainmenuButtonPushed(app, event)
            %RETURN TO MAIN MENU CODE
            question = "Are you sure you want to return to the main menu?";
            answer = questdlg(question,"Return to main menu","yes","no","yes"); 
            switch answer
                case "yes"
                    MainMenu;
                    app.UpscaleUIFigure.Visible = 'off';
                case "no"
                    delete(answer);
            end
        end

        % Button pushed function: SaveImageButton
        function SaveImageButtonPushed(app, event)
            %SAVE IMAGE 
            if(isempty(app.Image))
                noup = "No image to be saved.";
                warndlg(noup);
            else
                filter = {'*.jpg';'*.jpeg';'*.png';'*.*'};
                [file,path] = uiputfile(filter,'save image','SavedImage');
                app.save = [path,file];
                imwrite(app.ImgTmp,app.save);
            end
        end

        % Button pushed function: UpscaleButton
        function UpscaleButtonPushed(app, event)
            %for upscaling the image to the desired scale
            app.ImgTmp = imresize(app.ImgTmp,app.sloider);
            imshow(app.ImgTmp,'parent',app.UIAxes);
        end

        % Value changed function: ScaleSlider
        function ScaleSliderValueChanging(app, event)
            %slider to change scale for resizing
            changingValue = event.Value;
            inter = int8(changingValue);
            app.sloider = ceil(inter);
            dob = double(inter);
            app.ScaleSlider.Value = dob;
            app.Scale1Label.Text = "Scale : " + string(app.sloider);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UpscaleUIFigure = uifigure('Visible', 'off');
            app.UpscaleUIFigure.Name = 'Upscale';
            app.UIAxes = uiaxes(app.UpscaleUIFigure);
            app.UIAxes.Visible = 'off';
            app.UpscalingLabel = uilabel(app.UpscaleUIFigure);
            app.UpscalingLabel.Text = 'Upscaling';
            app.UpscaleButton = uibutton(app.UpscaleUIFigure, 'push');
            app.UpscaleButton.ButtonPushedFcn = createCallbackFcn(app, @UpscaleButtonPushed, true);
            app.UpscaleButton.Text = 'Upscale';
            app.UploadImageButton = uibutton(app.UpscaleUIFigure, 'push');
            app.UploadImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.UploadImageButton.Text = 'Upload Image';
            app.SaveImageButton = uibutton(app.UpscaleUIFigure, 'push');
            app.SaveImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButtonPushed, true);
            app.SaveImageButton.Text = 'Save Image';
            app.ReturntomainmenuButton = uibutton(app.UpscaleUIFigure, 'push');
            app.ReturntomainmenuButton.ButtonPushedFcn = createCallbackFcn(app, @ReturntomainmenuButtonPushed, true);
            app.ReturntomainmenuButton.Text = 'Return to main menu';
            app.ScaleSlider = uislider(app.UpscaleUIFigure);
            app.ScaleSlider.Limits = [1 10];
            app.ScaleSlider.MajorTicks = [1 10];
            app.ScaleSlider.MajorTickLabels = {'1', '10', ''};
            app.ScaleSlider.ValueChangedFcn = createCallbackFcn(app, @ScaleSliderValueChanging, true);
            app.ScaleSlider.MinorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.ScaleSlider.Value = 1;
            app.Scale1Label = uilabel(app.UpscaleUIFigure);
            app.Scale1Label.Text = 'Scale : 1';
            % Show the figure after all components are created
            app.UpscaleUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Upscaling

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UpscaleUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UpscaleUIFigure)
        end
    end
end